classdef app_gui_cost_function_tuning_front_end < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FinishYEditField               matlab.ui.control.NumericEditField
        FinishYEditFieldLabel          matlab.ui.control.Label
        StartYEditField                matlab.ui.control.NumericEditField
        StartYEditFieldLabel           matlab.ui.control.Label
        PlanPathButton                 matlab.ui.control.Button
        HeuristiccosttermweightsLabel  matlab.ui.control.Label
        CostfunctiontermweightsLabel   matlab.ui.control.Label
        SetStartGoalpairButton         matlab.ui.control.Button
        FinishXEditField               matlab.ui.control.NumericEditField
        FinishXEditFieldLabel          matlab.ui.control.Label
        StartXEditField                matlab.ui.control.NumericEditField
        distanceSlider_2               matlab.ui.control.Slider
        distanceSlider_2Label          matlab.ui.control.Label
        StartXEditFieldLabel           matlab.ui.control.Label
        MissionDefinitionLabel         matlab.ui.control.Label
        corridorwidthSlider            matlab.ui.control.Slider
        corridorwidthSliderLabel       matlab.ui.control.Label
        vgraphedgesSlider              matlab.ui.control.Slider
        vgraphedgesSliderLabel         matlab.ui.control.Label
        distanceSlider                 matlab.ui.control.Slider
        distanceSliderLabel            matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
    end
    
    methods (Access = private)
        % REVISION HISTORY:
        % As: script_test__polytope_canyon_replan_with_dilation
        % 2025_10_06 - S. Brennan
        % -- removed calls to fcn_util_load_test_map, replaced with fcn_BoundedAStar_loadTestMap
        % 2025_10_06 - S. Brennan
        % -- removed addpath calls
        % 2025_11_01 - S. Brennan
        % -- removed calls to fcn_BoundedAStar_loadTestMap, replaced with fcn_MapGen_loadTestMap


        function calculate_all_costs(app)
            shrunk_polytopes = app.Property.shrunk_polytopes;
            %% make all pts array
            point_tot = length([shrunk_polytopes.xv]); % total number of vertices in the polytopes
            beg_end = zeros(1,point_tot); % is the point the start/end of an obstacle
            curpt = 0;
            for poly = 1:size(shrunk_polytopes,2) % check each polytope
                verts = length(shrunk_polytopes(poly).xv);
                shrunk_polytopes(poly).obs_id = ones(1,verts)*poly; % obs_id is the same for every vertex on a single polytope
                beg_end([curpt+1,curpt+verts]) = 1; % the first and last vertices are marked with 1 and all others are 0
                curpt = curpt+verts;
            end
            obs_id = [shrunk_polytopes.obs_id];
            app.Property.all_pts = [[shrunk_polytopes.xv];[shrunk_polytopes.yv];1:point_tot;obs_id;beg_end]'; % all points [x y point_id obs_id beg_end]
            %% make vgraph
            app.Property.start = [app.Property.start_init size(app.Property.all_pts,1)+1 -1 1];
            app.Property.finish = [app.Property.finish_init size(app.Property.all_pts,1)+2 -1 1];
            finishes = [app.Property.all_pts; app.Property.start; app.Property.finish];
            starts = [app.Property.all_pts; app.Property.start; app.Property.finish];
            [app.Property.vgraph, visibility_results_all_pts] = fcn_visibility_clear_and_blocked_points_global(shrunk_polytopes, starts, finishes,1);
            %% make rgraph
            start_for_reachability = app.Property.start;
            start_for_reachability(4) = app.Property.start(3);
            finish_for_reachability = app.Property.finish;
            finish_for_reachability(4) = app.Property.finish(3);

            [app.Property.is_reachable, num_steps, app.Property.rgraph] = fcn_check_reachability(app.Property.vgraph,start_for_reachability(3),finish_for_reachability(3));
            mode = "xy spatial only";
            [app.Property.cgraph, app.Property.hvec] = fcn_algorithm_generate_cost_graph(app.Property.all_pts, app.Property.start, app.Property.finish, mode);
            mode = '2d';
            dilation_robustness_tensor = fcn_algorithm_generate_dilation_robustness_matrix(app.Property.all_pts, app.Property.start, app.Property.finish, app.Property.vgraph, mode, shrunk_polytopes);
            app.Property.dilation_robustness_matrix = max(dilation_robustness_tensor(:,:,1) , dilation_robustness_tensor(:,:,2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % addpath(strcat(pwd,'\..\..\PathPlanning_PathTools_PathClassLibrary\Functions'));
            % addpath(strcat(pwd,'\..\..\PathPlanning_MapTools_MapGenClassLibrary\Functions'));
            % addpath(strcat(pwd,'\..\..\Errata_Tutorials_DebugTools\Functions'));
            [app.Property.shrunk_polytopes, app.Property.start_init , app.Property.finish_init, app.Property.resolution_scale] = fcn_MapGen_loadTestMap(6);
            app.Property.start_init = app.Property.start_init(1,:);
            app.Property.finish_init = app.Property.finish_init(1,:);
            hold(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
            title(app.UIAxes,'Obstacle Field')
            xlabel(app.UIAxes,'x [km]');
            ylabel(app.UIAxes,'y [km]');
            app.Property.start_plot = plot(app.UIAxes,app.Property.start_init(1),app.Property.start_init(2),'xg','MarkerSize',6);
            app.Property.finish_plot = plot(app.UIAxes,app.Property.finish_init(1),app.Property.finish_init(2),'xr','MarkerSize',6);
            for j = 1:length(app.Property.shrunk_polytopes)
                 fill(app.UIAxes,app.Property.shrunk_polytopes(j).vertices(:,1)',app.Property.shrunk_polytopes(j).vertices(:,2),[0 0 1],'FaceAlpha',1)
            end
            calculate_all_costs(app);
        end

        % Button pushed function: PlanPathButton
        function PlanPathButtonPushed(app, event)
            
            % calculate visibility cost from vgraph
            visible_nodes_from_each_node = sum(app.Property.vgraph,2);
            inv_vis_cost = 10000*(1./(visible_nodes_from_each_node))';
            reachable_nodes_from_each_node = sum(app.Property.rgraph,2);
            inv_reach_cost = 10000*(1./(reachable_nodes_from_each_node))';
            
            % get weights from sliders
            w_dist = app.distanceSlider.Value;
            w_corridor = app.corridorwidthSlider.Value;
            w_dist2 = app.distanceSlider_2.Value;
            w_vis = app.vgraphedgesSlider.Value;

            % calcualte corridor widths from corridor width matrix
            inv_corridor_width = 1./app.Property.dilation_robustness_matrix;
            infinite_idx = find(inv_corridor_width==inf);
            inv_corridor_width(infinite_idx) = 10000;

            % weight costs
            hvec_mod =  w_dist2*app.Property.hvec + w_vis*(inv_reach_cost + inv_vis_cost);
            cgraph_mod = w_dist*app.Property.cgraph + w_corridor*inv_corridor_width;
            % and call planner
            [init_cost, init_route] = fcn_algorithm_Astar(app.Property.vgraph, cgraph_mod, hvec_mod, app.Property.all_pts, app.Property.start, app.Property.finish);
            % calculate route metrics
            route_x = init_route(:,1);
            route_y = init_route(:,2);
            lengths = diff([route_x(:) route_y(:)]);
            init_route_length = sum(sqrt(sum(lengths.*lengths,2)));
            % plot the path
            plot(app.UIAxes,init_route(:,1),init_route(:,2),'LineWidth',2);
            title_string = sprintf("path length = %.2f km",init_route_length);
            title(app.UIAxes,title_string);
        end

        % Button pushed function: SetStartGoalpairButton
        function SetStartGoalpairButtonPushed(app, event)
            app.Property.start_init(1) = app.StartXEditField.Value;
            app.Property.start_init(2) = app.StartYEditField.Value;
            app.Property.finish_init(1) = app.FinishXEditField.Value;
            app.Property.finish_init(2) = app.FinishYEditField.Value;
            delete(app.Property.start_plot);
            delete(app.Property.finish_plot);
            calculate_all_costs(app);
            app.Property.start_plot = plot(app.UIAxes,app.Property.start_init(1),app.Property.start_init(2),'xg','MarkerSize',6);
            app.Property.finish_plot = plot(app.UIAxes,app.Property.finish_init(1),app.Property.finish_init(2),'xr','MarkerSize',6);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.distanceSliderLabel = uilabel(app.LeftPanel);
            app.distanceSliderLabel.Text = 'distance';
            app.distanceSlider = uislider(app.LeftPanel);
            app.distanceSlider.Limits = [0 1];
            app.vgraphedgesSliderLabel = uilabel(app.LeftPanel);
            app.vgraphedgesSliderLabel.Text = 'vgraph edges';
            app.vgraphedgesSlider = uislider(app.LeftPanel);
            app.vgraphedgesSlider.Limits = [0 1];
            app.corridorwidthSliderLabel = uilabel(app.LeftPanel);
            app.corridorwidthSliderLabel.Text = 'corridor width';
            app.corridorwidthSlider = uislider(app.LeftPanel);
            app.corridorwidthSlider.Limits = [0 1];
            app.MissionDefinitionLabel = uilabel(app.LeftPanel);
            app.MissionDefinitionLabel.Text = 'Mission Definition';
            app.StartXEditFieldLabel = uilabel(app.LeftPanel);
            app.StartXEditFieldLabel.Text = 'Start X';
            app.distanceSlider_2Label = uilabel(app.LeftPanel);
            app.distanceSlider_2Label.Text = 'distance';
            app.distanceSlider_2 = uislider(app.LeftPanel);
            app.distanceSlider_2.Limits = [0 1];
            app.StartXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartXEditField.Value = 1000.6;
            app.FinishXEditFieldLabel = uilabel(app.LeftPanel);
            app.FinishXEditFieldLabel.Text = 'Finish X';
            app.FinishXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FinishXEditField.Value = 1024;
            app.SetStartGoalpairButton = uibutton(app.LeftPanel, 'push');
            app.SetStartGoalpairButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartGoalpairButtonPushed, true);
            app.SetStartGoalpairButton.Text = 'Set Start-Goal pair';
            app.CostfunctiontermweightsLabel = uilabel(app.LeftPanel);
            app.CostfunctiontermweightsLabel.Text = 'Cost function term weights';
            app.HeuristiccosttermweightsLabel = uilabel(app.LeftPanel);
            app.HeuristiccosttermweightsLabel.Text = 'Heuristic cost term weights';
            app.PlanPathButton = uibutton(app.LeftPanel, 'push');
            app.PlanPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlanPathButtonPushed, true);
            app.PlanPathButton.Text = 'Plan Path';
            app.StartYEditFieldLabel = uilabel(app.LeftPanel);
            app.StartYEditFieldLabel.Text = 'Start Y';
            app.StartYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartYEditField.Value = -4711.4;
            app.FinishYEditFieldLabel = uilabel(app.LeftPanel);
            app.FinishYEditFieldLabel.Text = 'Finish Y';
            app.FinishYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FinishYEditField.Value = -4716.1;
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '__________________________';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '__________________________';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_gui_cost_function_tuning_front_end

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end