classdef app_gui_cost_function_tuning_front_end < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FinishYEditField               matlab.ui.control.NumericEditField
        FinishYEditFieldLabel          matlab.ui.control.Label
        StartYEditField                matlab.ui.control.NumericEditField
        StartYEditFieldLabel           matlab.ui.control.Label
        PlanPathButton                 matlab.ui.control.Button
        HeuristiccosttermweightsLabel  matlab.ui.control.Label
        CostfunctiontermweightsLabel   matlab.ui.control.Label
        SetStartGoalpairButton         matlab.ui.control.Button
        FinishXEditField               matlab.ui.control.NumericEditField
        FinishXEditFieldLabel          matlab.ui.control.Label
        StartXEditField                matlab.ui.control.NumericEditField
        distanceSlider_2               matlab.ui.control.Slider
        distanceSlider_2Label          matlab.ui.control.Label
        StartXEditFieldLabel           matlab.ui.control.Label
        MissionDefinitionLabel         matlab.ui.control.Label
        corridorwidthSlider            matlab.ui.control.Slider
        corridorwidthSliderLabel       matlab.ui.control.Label
        vgraphedgesSlider              matlab.ui.control.Slider
        vgraphedgesSliderLabel         matlab.ui.control.Label
        distanceSlider                 matlab.ui.control.Slider
        distanceSliderLabel            matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath(strcat(pwd,'\..\..\PathPlanning_PathTools_PathClassLibrary\Functions'));
            addpath(strcat(pwd,'\..\..\PathPlanning_MapTools_MapGenClassLibrary\Functions'));
            addpath(strcat(pwd,'\..\..\Errata_Tutorials_DebugTools\Functions'));
            load(strcat(pwd,'\..\Test_Fixtures\flood_plains\flood_plain_5.mat'));
            app.Property.shrunk_polytopes = flood_plain_5;
            app.Property.start_init = [-78.01 41.06];
            app.Property.finish_init = [-77.75 40.93];
            hold(app.UIAxes, 'on');
            box(app.UIAxes, 'on');
            title(app.UIAxes,'Obstacle Field')
            xlabel(app.UIAxes,'x [km]');
            ylabel(app.UIAxes,'y [km]');
            app.Property.start_plot = plot(app.UIAxes,app.Property.start_init(1),app.Property.start_init(2),'xg','MarkerSize',6);
            app.Property.finish_plot = plot(app.UIAxes,app.Property.finish_init(1),app.Property.finish_init(2),'xr','MarkerSize',6);
            for j = 1:length(app.Property.shrunk_polytopes)
                 fill(app.UIAxes,app.Property.shrunk_polytopes(j).vertices(:,1)',app.Property.shrunk_polytopes(j).vertices(:,2),[0 0 1],'FaceAlpha',1)
            end
            % TODO make map, cgraph, vgraph, hvec (all possible forms) corridor width
            % store as app member variables
            % plot obstacle field
        end

        % Button pushed function: PlanPathButton
        function PlanPathButtonPushed(app, event)
            % TODO read weights from sliders, apply to cgraph, vgraph, hvec
            % etc. and call planner
            % plot the path
        end

        % Button pushed function: SetStartGoalpairButton
        function SetStartGoalpairButtonPushed(app, event)
            app.Property.start_init(1) = app.StartXEditField.Value;
            app.Property.start_init(2) = app.StartYEditField.Value;
            app.Property.finish_init(1) = app.FinishXEditField.Value;
            app.Property.finish_init(2) = app.FinishYEditField.Value;
            delete(app.Property.start_plot);
            delete(app.Property.finish_plot);
            app.Property.start_plot = plot(app.UIAxes,app.Property.start_init(1),app.Property.start_init(2),'xg','MarkerSize',6);
            app.Property.finish_plot = plot(app.UIAxes,app.Property.finish_init(1),app.Property.finish_init(2),'xr','MarkerSize',6);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.distanceSliderLabel = uilabel(app.LeftPanel);
            app.distanceSliderLabel.Text = 'distance';
            app.distanceSlider = uislider(app.LeftPanel);
            app.distanceSlider.Limits = [0 1];
            app.vgraphedgesSliderLabel = uilabel(app.LeftPanel);
            app.vgraphedgesSliderLabel.Text = 'vgraph edges';
            app.vgraphedgesSlider = uislider(app.LeftPanel);
            app.vgraphedgesSlider.Limits = [0 1];
            app.corridorwidthSliderLabel = uilabel(app.LeftPanel);
            app.corridorwidthSliderLabel.Text = 'corridor width';
            app.corridorwidthSlider = uislider(app.LeftPanel);
            app.corridorwidthSlider.Limits = [0 1];
            app.MissionDefinitionLabel = uilabel(app.LeftPanel);
            app.MissionDefinitionLabel.Text = 'Mission Definition';
            app.StartXEditFieldLabel = uilabel(app.LeftPanel);
            app.StartXEditFieldLabel.Text = 'Start X';
            app.distanceSlider_2Label = uilabel(app.LeftPanel);
            app.distanceSlider_2Label.Text = 'distance';
            app.distanceSlider_2 = uislider(app.LeftPanel);
            app.distanceSlider_2.Limits = [0 1];
            app.StartXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FinishXEditFieldLabel = uilabel(app.LeftPanel);
            app.FinishXEditFieldLabel.Text = 'Finish X';
            app.FinishXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetStartGoalpairButton = uibutton(app.LeftPanel, 'push');
            app.SetStartGoalpairButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartGoalpairButtonPushed, true);
            app.SetStartGoalpairButton.Text = 'Set Start-Goal pair';
            app.CostfunctiontermweightsLabel = uilabel(app.LeftPanel);
            app.CostfunctiontermweightsLabel.Text = 'Cost function term weights';
            app.HeuristiccosttermweightsLabel = uilabel(app.LeftPanel);
            app.HeuristiccosttermweightsLabel.Text = 'Heuristic cost term weights';
            app.PlanPathButton = uibutton(app.LeftPanel, 'push');
            app.PlanPathButton.ButtonPushedFcn = createCallbackFcn(app, @PlanPathButtonPushed, true);
            app.PlanPathButton.Text = 'Plan Path';
            app.StartYEditFieldLabel = uilabel(app.LeftPanel);
            app.StartYEditFieldLabel.Text = 'Start Y';
            app.StartYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FinishYEditFieldLabel = uilabel(app.LeftPanel);
            app.FinishYEditFieldLabel.Text = 'Finish Y';
            app.FinishYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '__________________________';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '__________________________';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_gui_cost_function_tuning_front_end

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end